classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        timeValLabel                   matlab.ui.control.Label
        zPosValLabel                   matlab.ui.control.Label
        yPosValLabel                   matlab.ui.control.Label
        xPosValLabel                   matlab.ui.control.Label
        TimesLabel                     matlab.ui.control.Label
        PositionZmLabel                matlab.ui.control.Label
        PositionYmLabel                matlab.ui.control.Label
        PositionXmLabel                matlab.ui.control.Label
        CONTROLPANELPanel              matlab.ui.container.Panel
        ZSpinner                       matlab.ui.control.Spinner
        YSpinner                       matlab.ui.control.Spinner
        TimeSpinner                    matlab.ui.control.Spinner
        XSpinner                       matlab.ui.control.Spinner
        UITable                        matlab.ui.control.Table
        LOADFILEFROMOUTPUTFOLDERLabel  matlab.ui.control.Label
        REFRESHButton                  matlab.ui.control.Button
        LOADButton                     matlab.ui.control.Button
        ZmSlider                       matlab.ui.control.Slider
        ZmSliderLabel                  matlab.ui.control.Label
        YmSlider                       matlab.ui.control.Slider
        YmSliderLabel                  matlab.ui.control.Label
        XmSlider                       matlab.ui.control.Slider
        XmSliderLabel                  matlab.ui.control.Label
        TIMEsSlider                    matlab.ui.control.Slider
        TIMEsSliderLabel               matlab.ui.control.Label
        RESULTListBox                  matlab.ui.control.ListBox
        colormap_YZ                    matlab.ui.control.UIAxes
        colormap_XY                    matlab.ui.control.UIAxes
        colormap_XZ                    matlab.ui.control.UIAxes
        posPlot                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Simulation data
    end
    
    
    methods (Access = private)
        
        function index = find_nearest_time_index(app, time)
             index = round(time / app.data.timeStep) + 1;
        end

        function idx = findClosestIndex(~,arr, value)
        [~, idx] = min(abs(arr - value));
        end

        function refreshTableData(app, timeIndex)
            % Extract drone types and positions
            droneData = app.data.allDroneData(timeIndex).droneData;

            tableData = [ {droneData.Type}' , num2cell(vertcat(droneData.Pos)) ];

            % Assign the table data to the UITable
            app.UITable.Data = tableData;
        end
        
        function refreshScatterPlot(app,timeIndex,xPlane,yPlane,zPlane)
            %Extracting drone Position
            droneData = app.data.allDroneData(timeIndex).droneData;
            positions = vertcat(droneData.Pos);
            types = {droneData.Type};
            
            % Clear previous plot and update scatter plot
            cla(app.posPlot); % Clear existing plot

            % Plot each group separately
            % Taxi Group (Red)
            taxiMask = strcmp(types, 'Taxi');
            scatter3(app.posPlot, positions(taxiMask, 1), positions(taxiMask, 2), positions(taxiMask, 3), 50, 'r', 'filled');
            hold(app.posPlot, 'on'); % Keep the plot active
    
            % Delivery Group (Green)
            deliveryMask = strcmp(types, 'Delivery');
            scatter3(app.posPlot, positions(deliveryMask, 1), positions(deliveryMask, 2), positions(deliveryMask, 3), 50, 'g', 'filled');
    
            % Recreational Group (Blue)
            recreationalMask = strcmp(types, 'Recreational');
            scatter3(app.posPlot, positions(recreationalMask, 1), positions(recreationalMask, 2), positions(recreationalMask, 3), 50, 'b', 'filled');
            
            %defining axis limits
            xLimits = [0, app.data.xMax];
            yLimits = [0, app.data.yMax];
            zLimits = [0,app.data.zMax];

            %Plot XY plane
            xFrame = [xLimits(1), xLimits(2), xLimits(2), xLimits(1), xLimits(1)];
            yFrame = [yLimits(1), yLimits(1), yLimits(2), yLimits(2), yLimits(1)];
            zFrame = ones(size(xFrame)) * zPlane; % Constant Z value for the frame

            plot3(app.posPlot,xFrame, yFrame, zFrame, 'r-', 'LineWidth', 2); % Red frame

            %Plot XZ plane
            xFrame = [xLimits(1), xLimits(2), xLimits(2), xLimits(1), xLimits(1)];
            yFrame = ones(size(xFrame)) * yPlane; % Constant Y value for the frame;
            zFrame = [zLimits(1), zLimits(1), zLimits(2), zLimits(2), zLimits(1)];

            plot3(app.posPlot,xFrame, yFrame, zFrame, 'b-', 'LineWidth', 2); % Blue frame

            %Plot YZ plane
            yFrame = [yLimits(1), yLimits(1), yLimits(2), yLimits(2), yLimits(1)];
            zFrame = [zLimits(1), zLimits(1), zLimits(2), zLimits(2), zLimits(1)];
            xFrame = ones(size(yFrame))* xPlane; % Constant X value for the frame;

            plot3(app.posPlot,xFrame, yFrame, zFrame, 'g-o', 'LineWidth', 2); % Green frame
            
            % Get buildings data from app structure
            buildings = app.data.buildings;
             % Define color for buildings
            buildingColor = [0.5, 0.5, 0.5]; % Gray

            % Loop through each building and draw it
            for j = 1:size(buildings, 1)
                % Extract building coordinates
                x1 = buildings(j, 1);
                y1 = buildings(j, 2);
                z1 = buildings(j, 3);
                x2 = buildings(j, 4);
                y2 = buildings(j, 5);
                z2 = buildings(j, 6);

                % Define vertices of the building (cuboid)
                vertices = [x1 y1 z1; x2 y1 z1; x2 y2 z1; x1 y2 z1;  % Bottom face
                    x1 y1 z2; x2 y1 z2; x2 y2 z2; x1 y2 z2]; % Top face

                % Define faces of the cuboid
                faces = [1 2 3 4; % Bottom
                    5 6 7 8; % Top
                    1 2 6 5; % Front
                    2 3 7 6; % Right
                    3 4 8 7; % Back
                    4 1 5 8]; % Left

                % Draw the building on the app's posPlot UIAxes
                patch(app.posPlot, 'Vertices', vertices, 'Faces', faces, ...
                    'FaceColor', buildingColor, 'EdgeColor', 'k', 'FaceAlpha', 0.5);
            end


            % Release the plot hold and ploting legend
            hold(app.posPlot, 'off');
            legend(app.posPlot,{'Taxi', 'Delivery', 'Recreational'}, 'Location', 'best');

            %setting axis limits
            xlim(app.posPlot,xLimits)
            ylim(app.posPlot,yLimits)
            zlim(app.posPlot,zLimits)
        end

        function refreshXYColormap(app,timeIndex,zVal)
            %creating XY plot
            zIdx = app.findClosestIndex(zVal,app.data.z);
            soundXY = squeeze(app.data.allSoundLevels(timeIndex, :, :, zIdx));
            pcolor(app.colormap_XY,app.data.x,app.data.y,soundXY)
            
            % Define corresponding RGB colors (normalized to 0-1 range)
            colors = [  0, 1, 0;   % Green (40-45 dB)
                        1, 1, 0;   % Yellow (45-50 dB)
                        1, 0.5, 0; % Bright Orange (50-55 dB)
                        1, 0.3, 0; % Deep Orange (55-60 dB)
                        1, 0, 0;   % Bright Red (60-65 dB)
                        0.7, 0, 0; % Deep Red (65-70 dB)
                        0.5, 0, 0.5; % Purple (70-75 dB)
                        0, 0.7, 1; % Light Blue (75-80 dB)
                        0, 0, 1];  % Blue (80+ dB)
            
            % Apply colormap in your plot
            colormap(app.colormap_XY,colors);
            clim(app.colormap_XY,[40 85]); % Adjust color axis limits
            colorbar(app.colormap_XY);

             %setting axis limits
            xlim(app.colormap_XY,[0,app.data.xMax])
            ylim(app.colormap_XY,[0,app.data.yMax])
        end


        function refreshXZColormap(app,timeIndex,yVal)
            %creating XY plot
            yIdx = app.findClosestIndex(yVal,app.data.y);
            %soundXZ = squeeze(app.data.allSoundLevels(timeIndex, :, yIdx,:));
            soundXZ = squeeze(app.data.allSoundLevels(timeIndex, yIdx, :,:))';
            pcolor(app.colormap_XZ,app.data.x,app.data.z,soundXZ)
            
            % Define corresponding RGB colors (normalized to 0-1 range)
            colors = [  0, 1, 0;   % Green (40-45 dB)
                        1, 1, 0;   % Yellow (45-50 dB)
                        1, 0.5, 0; % Bright Orange (50-55 dB)
                        1, 0.3, 0; % Deep Orange (55-60 dB)
                        1, 0, 0;   % Bright Red (60-65 dB)
                        0.7, 0, 0; % Deep Red (65-70 dB)
                        0.5, 0, 0.5; % Purple (70-75 dB)
                        0, 0.7, 1; % Light Blue (75-80 dB)
                        0, 0, 1];  % Blue (80+ dB)
            
            % Apply colormap in your plot
            colormap(app.colormap_XZ,colors);
            clim(app.colormap_XZ,[40 85]); % Adjust color axis limits
            colorbar(app.colormap_XZ);

            %setting axis limits
            xlim(app.colormap_XZ,[0,app.data.xMax]);
            ylim(app.colormap_XZ,[0,app.data.zMax]);
        end

        function refreshYZColormap(app,timeIndex,xVal)

            %creating YZ plot
            xIdx = app.findClosestIndex(xVal,app.data.x);
            %soundYZ = squeeze(app.data.allSoundLevels(timeIndex, xIdx, :,:));
            soundYZ = squeeze(app.data.allSoundLevels(timeIndex,: , xIdx,:))';
            pcolor(app.colormap_YZ,app.data.y,app.data.z,soundYZ)

            % Define corresponding RGB colors (normalized to 0-1 range)
            colors = [  0, 1, 0;   % Green (40-45 dB)
                1, 1, 0;   % Yellow (45-50 dB)
                1, 0.5, 0; % Bright Orange (50-55 dB)
                1, 0.3, 0; % Deep Orange (55-60 dB)
                1, 0, 0;   % Bright Red (60-65 dB)
                0.7, 0, 0; % Deep Red (65-70 dB)
                0.5, 0, 0.5; % Purple (70-75 dB)
                0, 0.7, 1; % Light Blue (75-80 dB)
                0, 0, 1];  % Blue (80+ dB)

            % Apply colormap in your plot
            colormap(app.colormap_YZ,colors);
            clim(app.colormap_YZ,[40 85]); % Adjust color axis limits
            colorbar(app.colormap_YZ);

            %setting axis limits
            xlim(app.colormap_YZ,[0,app.data.yMax]);
            ylim(app.colormap_YZ,[0,app.data.zMax]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            listing  = dir("output");
            matFiles = {listing(~[listing(:).isdir] & endsWith({listing(:).name}, '.mat')).name};

            app.RESULTListBox.Items = matFiles;
            app.RESULTListBox.Value = matFiles(1);
            
            %Disabling sliders until data is loaded
            app.TIMEsSlider.Enable = "off";
            app.XmSlider.Enable ="off";
            app.YmSlider.Enable = "off";
            app.ZmSlider.Enable = "off";

            app.TimeSpinner.Enable = "off";
            app.XSpinner.Enable = "off";
            app.YSpinner.Enable = "off";
            app.ZSpinner.Enable = "off";


        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            name = join(["output",app.RESULTListBox.Value],"\");
            app.data = load(name);

            % Ensure app.data.allDroneData exists and has a Type field
                if isfield(app.data, 'allDroneData') ...
                % && ~isempty({app.data.allDroneData.droneData.Type})

                    %Setting up sliders and Spinners limits and initial values
                    app.TIMEsSlider.Limits = [0,max(app.data.totalTime)];
                    app.TIMEsSlider.Value = 0;
                    app.TIMEsSlider.Enable = "on";

                    app.TimeSpinner.Limits = [0,max(app.data.totalTime)];
                    app.TimeSpinner.Value = 0;
                    app.TimeSpinner.Enable = "on";

                    app.XmSlider.Limits = [min(app.data.x),max(app.data.x)];
                    app.XmSlider.Value = min(app.data.x);
                    app.XmSlider.Enable = "on";

                    app.XSpinner.Limits = [min(app.data.x),max(app.data.x)];
                    app.XSpinner.Value = min(app.data.x);
                    app.XSpinner.Enable = "on";

                    app.YmSlider.Limits = [min(app.data.y),max(app.data.y)];
                    app.YmSlider.Value = min(app.data.y);
                    app.YmSlider.Enable = "on";

                    app.YSpinner.Limits = [min(app.data.y),max(app.data.y)];
                    app.YSpinner.Value = min(app.data.y);
                    app.YSpinner.Enable = "on";                    

                    app.ZmSlider.Limits = [min(app.data.z),max(app.data.z)];
                    app.ZmSlider.Value = min(app.data.z);
                    app.ZmSlider.Enable = "on";

                    app.ZSpinner.Limits = [min(app.data.z),max(app.data.z)];
                    app.ZSpinner.Value = min(app.data.z);
                    app.ZSpinner.Enable = "on";

                    %Displaying values on labels
                    app.timeValLabel.Text = num2str(app.TIMEsSlider.Value,2);
                    app.xPosValLabel.Text = num2str(app.XmSlider.Value,2);
                    app.yPosValLabel.Text = num2str(app.YmSlider.Value,2);
                    app.zPosValLabel.Text = num2str(app.ZmSlider.Value,2);
                    
                    %Refershing data in the table
                    timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
                    app.refreshTableData(timeIndex);

                    %Plotting 
                    app.refreshScatterPlot(timeIndex,app.XmSlider.Value,app.YmSlider.Value,app.ZmSlider.Value); %Scatter plot
                    app.refreshXYColormap(timeIndex,app.ZmSlider.Value); % XY plane colormap
                    app.refreshXZColormap(timeIndex,app.YmSlider.Value); % XZ plane colormap
                    app.refreshYZColormap(timeIndex,app.XmSlider.Value); % YZ plane colormap
                else
                    % Handle empty or missing data case
                    app.UITable.Data = {'Error', 'No Data Available'};
                end
        end

        % Button pushed function: REFRESHButton
        function REFRESHButtonPushed(app, event)
            listing  = {dir("output").name};
            app.RESULTListBox.Items = listing;
            app.RESULTListBox.Value = listing(1);
        end

        % Value changed function: TIMEsSlider
        function TIMEsSliderValueChanged(app, event)
            value = app.TIMEsSlider.Value;
            app.TimeSpinner.Value = value;

            %setting time label value
            app.timeValLabel.Text = num2str(value,2);

            %Refershing data in the table
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshTableData(timeIndex);

            %Refreshing Plots
            app.refreshScatterPlot(timeIndex,app.XmSlider.Value,app.YmSlider.Value,app.ZmSlider.Value); %Scatter plot
            app.refreshXYColormap(timeIndex,app.ZmSlider.Value); % XY plane colormap
            app.refreshXZColormap(timeIndex,app.YmSlider.Value); % XZ plane colormap
            app.refreshYZColormap(timeIndex,app.XmSlider.Value); % YZ plane colormap
        end

        % Value changed function: XmSlider
        function XmSliderValueChanged(app, event)
            value = app.XmSlider.Value;
            app.XSpinner.Value = value;

            %setting x label value
            app.xPosValLabel.Text = num2str(value,2);

            %refreshing YZ colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshYZColormap(timeIndex,value);
            refreshScatterPlot(app,timeIndex,value,app.YmSlider.Value,app.ZmSlider.Value);
        end

        % Value changed function: YmSlider
        function YmSliderValueChanged(app, event)
            value = app.YmSlider.Value;
            app.YSpinner.Value = value;

            %setting y label value
            app.yPosValLabel.Text = num2str(value,2);

            %refreshing XZ colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshXZColormap(timeIndex,value);
            refreshScatterPlot(app,timeIndex,app.XmSlider.Value,value,app.ZmSlider.Value);
        end

        % Value changed function: ZmSlider
        function ZmSliderValueChanged(app, event)
            value = app.ZmSlider.Value;
            app.ZSpinner.Value = value;

            %setting z label value
            app.zPosValLabel.Text = num2str(value,2);

            %refreshing XY colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshXYColormap(timeIndex,value);
            refreshScatterPlot(app,timeIndex,app.XmSlider.Value,app.YmSlider.Value,value)
        end

        % Value changing function: ZmSlider
        function ZmSliderValueChanging(app, event)
            changingValue = event.Value;

            %setting z label value
            app.zPosValLabel.Text = num2str(changingValue,2);

            %refreshing XY colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshXYColormap(timeIndex,changingValue);
            refreshScatterPlot(app,timeIndex,app.XmSlider.Value,app.YmSlider.Value,changingValue)
        end

        % Value changing function: YmSlider
        function YmSliderValueChanging(app, event)
            changingValue = event.Value;

            %setting y label value
            app.yPosValLabel.Text = num2str(changingValue,2);

            %refreshing XZ colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshXZColormap(timeIndex,changingValue);
            refreshScatterPlot(app,timeIndex,app.XmSlider.Value,changingValue,app.ZmSlider.Value)
        end

        % Value changing function: XmSlider
        function XmSliderValueChanging(app, event)
            changingValue = event.Value;
            
            %setting x label value
            app.xPosValLabel.Text = num2str(changingValue,2);

            %refreshing YZ colormap
            timeIndex = app.find_nearest_time_index(app.TIMEsSlider.Value);
            app.refreshYZColormap(timeIndex,changingValue);
            refreshScatterPlot(app,timeIndex,changingValue,app.YmSlider.Value,app.ZmSlider.Value);
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.XmSlider.Value = value;
            app.XmSliderValueChanged();
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
            value = app.TimeSpinner.Value;
            app.TIMEsSlider.Value = value;
            app.TIMEsSliderValueChanged();
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.YmSlider.Value = value;
            app.YmSliderValueChanged();
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            app.ZmSlider.Value = value;
            app.ZmSliderValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.posPlot = uiaxes(app.UIFigure);
            title(app.posPlot, 'UAV POSITINOS')
            xlabel(app.posPlot, 'X [m]')
            ylabel(app.posPlot, 'Y [m]')
            zlabel(app.posPlot, 'Z [m]')
            app.colormap_XZ = uiaxes(app.UIFigure);
            title(app.colormap_XZ, 'Sound level on XZ plane [dB]')
            xlabel(app.colormap_XZ, 'X')
            ylabel(app.colormap_XZ, 'Z')
            zlabel(app.colormap_XZ, 'Z')
            app.colormap_XY = uiaxes(app.UIFigure);
            title(app.colormap_XY, 'Sound level on XY plane [dB]')
            xlabel(app.colormap_XY, 'X')
            ylabel(app.colormap_XY, 'Y')
            zlabel(app.colormap_XY, 'Z')
            app.colormap_YZ = uiaxes(app.UIFigure);
            title(app.colormap_YZ, 'Sound level on YZ plane [dB]')
            xlabel(app.colormap_YZ, 'Y')
            ylabel(app.colormap_YZ, 'Z')
            zlabel(app.colormap_YZ, 'Z')
            app.CONTROLPANELPanel = uipanel(app.UIFigure);
            app.CONTROLPANELPanel.Title = 'CONTROL PANEL';
            app.RESULTListBox = uilistbox(app.CONTROLPANELPanel);
            app.RESULTListBox.Items = {};
            app.RESULTListBox.Value = {};
            app.TIMEsSliderLabel = uilabel(app.CONTROLPANELPanel);
            app.TIMEsSliderLabel.Text = 'TIME [s]';
            app.TIMEsSlider = uislider(app.CONTROLPANELPanel);
            app.TIMEsSlider.ValueChangedFcn = createCallbackFcn(app, @TIMEsSliderValueChanged, true);
            app.XmSliderLabel = uilabel(app.CONTROLPANELPanel);
            app.XmSliderLabel.Text = 'X [m]';
            app.XmSlider = uislider(app.CONTROLPANELPanel);
            app.XmSlider.ValueChangedFcn = createCallbackFcn(app, @XmSliderValueChanged, true);
            app.XmSlider.ValueChangingFcn = createCallbackFcn(app, @XmSliderValueChanging, true);
            app.YmSliderLabel = uilabel(app.CONTROLPANELPanel);
            app.YmSliderLabel.Text = 'Y [m]';
            app.YmSlider = uislider(app.CONTROLPANELPanel);
            app.YmSlider.ValueChangedFcn = createCallbackFcn(app, @YmSliderValueChanged, true);
            app.YmSlider.ValueChangingFcn = createCallbackFcn(app, @YmSliderValueChanging, true);
            app.ZmSliderLabel = uilabel(app.CONTROLPANELPanel);
            app.ZmSliderLabel.Text = 'Z [m]';
            app.ZmSlider = uislider(app.CONTROLPANELPanel);
            app.ZmSlider.ValueChangedFcn = createCallbackFcn(app, @ZmSliderValueChanged, true);
            app.ZmSlider.ValueChangingFcn = createCallbackFcn(app, @ZmSliderValueChanging, true);
            app.LOADButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.REFRESHButton = uibutton(app.CONTROLPANELPanel, 'push');
            app.REFRESHButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHButtonPushed, true);
            app.REFRESHButton.Text = 'REFRESH';
            app.LOADFILEFROMOUTPUTFOLDERLabel = uilabel(app.CONTROLPANELPanel);
            app.LOADFILEFROMOUTPUTFOLDERLabel.Text = 'LOAD FILE FROM  OUTPUT FOLDER';
            app.UITable = uitable(app.CONTROLPANELPanel);
            app.UITable.ColumnName = {'Type'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.XSpinner = uispinner(app.CONTROLPANELPanel);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.TimeSpinner = uispinner(app.CONTROLPANELPanel);
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.YSpinner = uispinner(app.CONTROLPANELPanel);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinner = uispinner(app.CONTROLPANELPanel);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.PositionXmLabel = uilabel(app.UIFigure);
            app.PositionXmLabel.Text = 'Position X [m]:';
            app.PositionYmLabel = uilabel(app.UIFigure);
            app.PositionYmLabel.Text = 'Position Y [m]:';
            app.PositionZmLabel = uilabel(app.UIFigure);
            app.PositionZmLabel.Text = 'Position Z [m]:';
            app.TimesLabel = uilabel(app.UIFigure);
            app.TimesLabel.Text = 'Time [s]:';
            app.xPosValLabel = uilabel(app.UIFigure);
            app.xPosValLabel.Text = '';
            app.yPosValLabel = uilabel(app.UIFigure);
            app.yPosValLabel.Text = '';
            app.zPosValLabel = uilabel(app.UIFigure);
            app.zPosValLabel.Text = '';
            app.timeValLabel = uilabel(app.UIFigure);
            app.timeValLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end